classdef QCS_ActuatorApp < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ConfigurationTab                matlab.ui.container.Tab
        CurrentMappingLabel             matlab.ui.control.Label
        NumOfCDbinsEditField            matlab.ui.control.NumericEditField
        NoofCDBinsEditFieldLabel        matlab.ui.control.Label
        NumOfQCSbinsEditField           matlab.ui.control.NumericEditField
        NoofQCSBinsEditFieldLabel       matlab.ui.control.Label
        NumOfActuatorsEditField         matlab.ui.control.NumericEditField
        NoActuatorsLabel                matlab.ui.control.Label
        UnitsToggleButton               matlab.ui.control.StateButton
        ScannerIDEditField              matlab.ui.control.EditField
        ScannerIDEditFieldLabel         matlab.ui.control.Label
        HiScannerEdge_cdbinEditField    matlab.ui.control.NumericEditField
        HiScannerEdgeEditFieldLabel     matlab.ui.control.Label
        LoScannerEdge_cdbinEditField    matlab.ui.control.NumericEditField
        LoScannerEdgeEditFieldLabel     matlab.ui.control.Label
        HiActuatorOffsetEditField       matlab.ui.control.NumericEditField
        HiActuatorOffsetEditFieldLabel  matlab.ui.control.Label
        LoActuatorOffsetEditField       matlab.ui.control.NumericEditField
        LoActuatorOffsetEditFieldLabel  matlab.ui.control.Label
        SaveButton                      matlab.ui.control.Button
        LoadButton                      matlab.ui.control.Button
        ActuatorWidthEditField          matlab.ui.control.NumericEditField
        ActuatorWidthEditFieldLabel     matlab.ui.control.Label
        CDBinWidthEditField             matlab.ui.control.NumericEditField
        CDBinWidthEditFieldLabel        matlab.ui.control.Label
        QCSBinWidthEditField            matlab.ui.control.NumericEditField
        QCSBinWidthEditFieldLabel       matlab.ui.control.Label
        CDControlDropDown               matlab.ui.control.DropDown
        CDControlDropDownLabel          matlab.ui.control.Label
        SiteNameEditField               matlab.ui.control.EditField
        SiteNameEditFieldLabel          matlab.ui.control.Label
        LoadTab                         matlab.ui.container.Tab
        PreprocessTab                   matlab.ui.container.Tab
        SpatialTab                      matlab.ui.container.Tab
        SymmetryLabel                   matlab.ui.control.Label
        DeviationLabel                  matlab.ui.control.Label
        ExpectedWidthLabel              matlab.ui.control.Label
        WidthResultLabel                matlab.ui.control.Label
        CoMResultLabel                  matlab.ui.control.Label
        RunSpatialButton                matlab.ui.control.Button
        SpatialTitleLabel               matlab.ui.control.Label
        ProfileAxes                     matlab.ui.control.UIAxes
        TemporalTab                     matlab.ui.container.Tab
        ReportTab                       matlab.ui.container.Tab
        SyntheticDataGeneratorTab       matlab.ui.container.Tab
        LoadConfigButton                matlab.ui.control.Button
        TabGroup2                       matlab.ui.container.TabGroup
        QCSScanDynamicsTab              matlab.ui.container.Tab
        TimeConstantEditField           matlab.ui.control.NumericEditField
        TimeConstantEditFieldLabel      matlab.ui.control.Label
        TimeDelayEditField              matlab.ui.control.NumericEditField
        TimeDelayEditFieldLabel         matlab.ui.control.Label
        ResponseGainEditField           matlab.ui.control.NumericEditField
        ResponseGainEditFieldLabel      matlab.ui.control.Label
        ScanTimeEditField               matlab.ui.control.NumericEditField
        ScanTimeEditFieldLabel          matlab.ui.control.Label
        SampleRateEditField             matlab.ui.control.NumericEditField
        SampleRateEditFieldLabel        matlab.ui.control.Label
        NoisePanel12Tab                 matlab.ui.container.Tab
        Level2EditField                 matlab.ui.control.NumericEditField
        Level2EditFieldLabel            matlab.ui.control.Label
        Level1EditField                 matlab.ui.control.NumericEditField
        Level1EditFieldLabel            matlab.ui.control.Label
        Noise2DropDown                  matlab.ui.control.DropDown
        Noise2DropDownLabel             matlab.ui.control.Label
        EnableNoise2CheckBox            matlab.ui.control.CheckBox
        Noise1DropDown                  matlab.ui.control.DropDown
        Noise1DropDownLabel             matlab.ui.control.Label
        EnableNoise1CheckBox            matlab.ui.control.CheckBox
        NoisePanel24Tab                 matlab.ui.container.Tab
        Level4EditField                 matlab.ui.control.NumericEditField
        Level4EditFieldLabel            matlab.ui.control.Label
        Level3EditField                 matlab.ui.control.NumericEditField
        Level3EditFieldLabel            matlab.ui.control.Label
        Noise4DropDown                  matlab.ui.control.DropDown
        Noise4DropDownLabel             matlab.ui.control.Label
        Noise3DropDown                  matlab.ui.control.DropDown
        Noise3DropDownLabel             matlab.ui.control.Label
        EnableNoise4CheckBox            matlab.ui.control.CheckBox
        EnableNoise3CheckBox            matlab.ui.control.CheckBox
        SaveConfigButton                matlab.ui.control.Button
        GenerateButton                  matlab.ui.control.Button
        UIAxes                          matlab.ui.control.UIAxes
    end

% ============================================================
% ============== PRIVATE =====================================
% ============================================================


% ==============================================================
% PRIVATE PROPERTIES (Internal State)
% ============================================================== 

properties (Access = private)
    M           double = []    % Generated data matrix
    sec         double = []    % Time vector
    preIdx      double = []    % Pre-bump indices
    postIdx     double = []    % Post-bump indices
    info        struct = struct()  % Metadata
    opts        struct = struct()  % Config
    S               struct = struct() % Spatial results ← ADD THIS
    bumpedActuator  double = []
    
end

properties (Access = public)
        % Data
    RawMatrix                   double = []
    CurrentMatrix               double = []
    Sec                         double = []
        % Internal values — ALWAYS in mm
    InternalQCSBinWidth         double = 0
    InternalCDBinWidth          double = 0
    InternalActuatorWidth       double = 0
    InternalConfigLoaded logical = false
end

% ============================================================ 
% == This Helper is used for Bin Width Units swiching labels
% ============================================================

methods (Access = private)
%        function str = ifelse(app, cond, a, b)
%            if cond
%                str = a;
%            else
%                str = b;
%            end
%        end


end

% ============================================================
% ============== PUBLIC ======================================
% ============================================================


methods (Access = public)
% ============================================================ 
% == Update unit Labels and Display
% ============================================================
    function updateUnitLabelsAndDisplay(app)
    unit = ifelse(app.UnitsToggleButton.Value, 'mm', 'in');
    factor = ifelse(app.UnitsToggleButton.Value, 1, 25.4);
    
    app.UnitsToggleButton.Text = unit;
    
    % Update labels
    app.QCSBinWidthEditFieldLabel.Text = ['QCS Bin Width (', unit, ')'];
    app.CDBinWidthEditFieldLabel.Text  = ['CD Bin Width (', unit, ')'];
    app.ActuatorWidthEditFieldLabel.Text = ['Actuator Width (', unit, ')'];
    
    % Update display values
    app.QCSBinWidthEditField.Value = app.InternalQCSBinWidth / factor;
    app.CDBinWidthEditField.Value  = app.InternalCDBinWidth  / factor;
    app.ActuatorWidthEditField.Value = app.InternalActuatorWidth / factor;
    
    app.StatusLabel.Text = ['Units: ', unit];
    end
end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Default: mm
    app.UnitsToggleButton.Value = true;
    app.UnitsToggleButton.Text = 'mm';
    updateUnitLabelsAndDisplay(app);

    % === OPEN TO CONFIGURATION TAB ===
    app.TabGroup.SelectedTab = app.ConfigurationTab;

    app.StatusLabel.Text = 'Ready';
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
        

% ======================================================================
% ==
% ==               Save Configuration Button
% ==                       Callback
% ==

   
 cfg = config.gatherConfigFromUI(app);
savePath = config.writeConfigToDisk(cfg);
if ~isempty(savePath)
    app.StatusLabel.Text = ['Saved: ', savePath];
else
    app.StatusLabel.Text = 'Save cancelled.';
end


% ==
% ==              End of Save Configuration Button
% ==                       Callback
% ==
% ======================================================================
        end

        % Value changed function: UnitsToggleButton
        function UnitsToggleButtonValueChanged(app, event)

% ======================================================================
% ==
% ==          Units Toggle Button - Value Changed
% ==                     Callback
% ==

        updateUnitLabelsAndDisplay(app);


% ==
% ==               Units Toggle Button
% ==                 aEnd of Callback
% ==
% ======================================================================
        end

        % Button pushed function: LoadButton
        function LoadButtonPushed(app, event)
% ======================================================================
% ==
% ==               Load Configuration Button
% ==                      Callback
% ==

            cfg = config.loadConfigFromDisk();
            if isempty(cfg), return; end
            config.applyConfigToUI(app, cfg);
            app.StatusLabel.Text = ['Loaded: ', cfg.siteName];


% ==
% ==             End of Load Configuration Button
% ==                     Callback
% ==
% ======================================================================
        end

        % Button pushed function: RunSpatialButton
        function RunSpatialButtonPushed(app, event)
if isempty(app.M) || isempty(app.preIdx) || isempty(app.postIdx) || isempty(app.opts)
        app.StatusLabel.Text = 'Generate data first';
        return;
    end

    try
        % === RUN ANALYSIS ===
        S = analyze_spatial(app.M, app.preIdx, app.postIdx, app.opts, app.info.bumpedActuator);
        app.S = S;

        % === DIAGNOSTIC PLOT ===
        if isfield(S, 'delta') && isfield(S, 'Zone_Boundary_Bins')
            figure('Name', 'Zone Mapping Debug');
            plot(1:length(S.delta), S.delta, 'k-', 'LineWidth', 1.5);
            hold on;
            arrayfun(@(b) xline(b, '--r', 'LineWidth', 1.2), S.Zone_Boundary_Bins);
            act_lo = S.Zone_Boundary_Bins(S.bumpedActuator);
            act_hi = S.Zone_Boundary_Bins(S.bumpedActuator + 1);
            xline(act_lo, '-g', 'LineWidth', 2);
            xline(act_hi, '-g', 'LineWidth', 2);
            text(act_lo, min(S.delta)*0.95, ' Act Lo', 'Color', 'g', 'FontWeight', 'bold', 'HorizontalAlignment', 'right');
            text(act_hi, min(S.delta)*0.95, ' Act Hi ', 'Color', 'g', 'FontWeight', 'bold', 'HorizontalAlignment', 'left');
            title('Delta Profile with Actuator Zone');
            xlabel('CD Bin'); ylabel('Delta (nm)');
            grid on; hold off;
        end

        % === SAFE ZONE TABLE ===
        try
            nAct = S.Num_Actuators;
            if nAct < 1, nAct = 1; end
            boundaries = S.Zone_Boundary_Bins;
            if length(boundaries) < nAct + 1
                boundaries(end+1:nAct+1) = boundaries(end);
            elseif length(boundaries) > nAct + 1
                boundaries = boundaries(1:nAct+1);
            end
            loBins  = boundaries(1:nAct)';
            hiBins  = boundaries(2:nAct+1)';
            centers = (loBins + hiBins) / 2;
            zoneTable = table((1:nAct)', loBins, centers, hiBins, ...
                'VariableNames', {'Actuator', 'Lo_Bin', 'Center_Bin', 'Hi_Bin'});
            disp('=== ACTUATOR ZONE MAPPING ==='); disp(zoneTable);
            if isprop(app, 'ZoneTable') && ~isempty(app.ZoneTable.ColumnName)
                app.ZoneTable.Data = table2cell(zoneTable);
                app.ZoneTable.ColumnName = zoneTable.Properties.VariableNames;
            end
        catch ME_table
            disp(['Table warning: ', ME_table.message]);
        end

        % === UPDATE LABELS ===
        app.CoMResultLabel.Text       = sprintf('Center of Mass: %.2f mm', S.cm_mm);
        app.WidthResultLabel.Text     = sprintf('Measured Width: %.2f mm', S.width_mm);
        app.ExpectedWidthLabel.Text   = sprintf('Expected Width: %.2f mm', S.expectedWidth_mm);
        app.DeviationLabel.Text       = sprintf('Deviation: %.1f%%', S.widthDeviation_pct);
        app.SymmetryLabel.Text        = sprintf('Symmetry: %.1f%%', S.symmetry_pct);

        % === MAIN PLOT ===
        cla(app.ProfileAxes);
        plot(app.ProfileAxes, S.profile_bins, S.profile_nm, 'b-', 'LineWidth', 2);
        hold(app.ProfileAxes, 'on');
        xlabel(app.ProfileAxes, 'CD Bin');
        ylabel(app.ProfileAxes, 'Response (nm)');
        hold(app.ProfileAxes, 'off');
        grid(app.ProfileAxes, 'on');

        app.StatusLabel.Text = 'Spatial analysis complete';

    catch ME
        app.StatusLabel.Text = ['Error: ', ME.message];
    end
   

        end

        % Button pushed function: GenerateButton
        function GenerateButtonPushed(app, event)

 
% =========================================================================
% =========================================================================
%          Synthetic Generator - "Generate Button"
%                -- Start if Callback --

    % === 1. VALIDATE ===
    if app.InternalCDBinWidth <= 0 || app.InternalActuatorWidth <= 0
        app.StatusLabel.Text = 'Error: Load site config first';
        return;
    end

    %if ~app.InternalConfigLoaded
    %    uialert(app.UIFigure, 'Load site config first!', 'Config Missing');
    %    return;
    %end

    % === 2. BUILD opts (CD-ONLY) ===
    opts = struct(...
        'cdBinWidth_mm',        app.InternalCDBinWidth, ...
        'actuatorWidth_mm',     app.InternalActuatorWidth, ...
        'actuatorLoOffset',     app.LoActuatorOffsetEditField.Value, ...
        'actuatorHiOffset',     app.HiActuatorOffsetEditField.Value, ...
        'scannerLoEdge_cdbin',  app.LoScannerEdge_cdbinEditField.Value, ...
        'scannerHiEdge_cdbin',  app.HiScannerEdge_cdbinEditField.Value, ...
        'cdBin_numOf',          app.NumOfCDbinsEditField.Value, ...
        'numActuators',         app.NumOfActuatorsEditField.Value ...  % ← NEW
    );

    % === 3. BUILD qcsOpts ===
    qcsOpts = struct(...
        'sampleRate',   app.SampleRateEditField.Value, ...
        'scanPeriod',   app.ScanTimeEditField.Value, ...
        'gain',         app.ResponseGainEditField.Value, ...
        'delay',        app.TimeDelayEditField.Value, ...
        'tau',          app.TimeConstantEditField.Value ...
    );

    % === 4. BUILD noiseList ===
    noiseList = {};
    for i = 1:4
        enableBox = app.(['EnableNoise', num2str(i), 'CheckBox']);
        typeDrop  = app.(['Noise', num2str(i), 'DropDown']);
        levelFld  = app.(['Level', num2str(i), 'EditField']);
        if enableBox.Value
            noiseList{end+1} = struct(...
                'enable', true, ...
                'type',   typeDrop.Value, ...
                'level',  levelFld.Value ...
            );
        end
    end

    % === 5. GENERATE DATA ===
    try
   %     bumpedActuator = round(app.NumOfActuatorsEditField.Value / 2);  % e.g., 3 for 5 actuators
        disp('→ generate_qcs_data');
        % In GenerateButtonPushed
[M, sec, info, preIdx, postIdx] = generate_qcs_data(opts, noiseList, qcsOpts);  % Let it auto-center

app.M = M;
app.sec = sec;
app.preIdx = preIdx;
app.postIdx = postIdx;
app.info = info;
app.opts = opts;
app.bumpedActuator = info.bumpedActuator;  % ← FROM info

        % === 7. PLOT AVERAGE PROFILE ===
        cla(app.UIAxes);
        plot(app.UIAxes, sec, mean(M, 2), 'b-', 'LineWidth', 1.5);
        xlabel(app.UIAxes, 'Time (sec)'); ylabel(app.UIAxes, 'Height (nm)');
        title(app.UIAxes, 'Synthetic QCS Profile (Avg)');
        grid(app.UIAxes, 'on');

        % === 8. SWITCH TO SPATIAL TAB ===
        app.TabGroup.SelectedTab = app.SpatialTab;
%JDL

        % === 9. AUTO-RUN SPATIAL ANALYSIS ===
        app.RunSpatialButtonPushed();  % ← Let RunSpatial do the rest

        app.StatusLabel.Text = 'Synthetic data generated & analyzed';

    catch ME
        app.StatusLabel.Text = ['Error: ', ME.message];
        disp(getReport(ME));
    end


%          Synthetic Generator - "Generate Button"
%                -- End of Callback --
% =========================================================================
% =========================================================================
        end

        % Button pushed function: SaveConfigButton
        function SaveConfigButtonPushed(app, event)
% =========================================================================
% =========================================================================
%          Synthetic Generator - "Save Configuration Button"
%                -- Start if Callback --

            cfg = config.gatherConfigFromUI(app);
    
    % Add synthetic params
    cfg.qcsSampleRate = app.SampleRateEditField.Value;
    cfg.qcsScanTime   = app.ScanTimeEditField.Value;
    cfg.qcsGain       = app.ResponseGainEditField.Value;
    cfg.qcsDelay      = app.TimeDelayEditField.Value;
    cfg.qcsTau        = app.TimeConstantEditField.Value;

    % Add noise
    cfg.noise = {};
    for i = 1:4
        if eval(['app.EnableNoise', num2str(i), 'CheckBox.Value'])
            cfg.noise{end+1} = struct(...
                'type',  eval(['app.Noise', num2str(i), 'DropDown.Value']), ...
                'level', eval(['app.Level', num2str(i), 'EditField.Value']) ...
            );
        end
    end

    savePath = config.writeConfigToDisk(cfg);
    if ~isempty(savePath)
        app.StatusLabel.Text = ['Config saved: ', savePath];
    else
        app.StatusLabel.Text = 'Save cancelled';
    end

%          Synthetic Generator - "Save Configuration Button"
%                -- End of Callback --
% =========================================================================
% =========================================================================    
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ConfigurationTab = uitab(app.TabGroup);
            app.ConfigurationTab.Title = 'Configuration';
            app.SiteNameEditFieldLabel = uilabel(app.ConfigurationTab);
            app.SiteNameEditFieldLabel.Text = 'Site Name';
            app.SiteNameEditField = uieditfield(app.ConfigurationTab, 'text');
            app.CDControlDropDownLabel = uilabel(app.ConfigurationTab);
            app.CDControlDropDownLabel.Text = 'CD Control';
            app.CDControlDropDown = uidropdown(app.ConfigurationTab);
            app.CDControlDropDown.Items = {'AutoSlice', 'ProFlow', 'Steambox', 'CalTrol'};
            app.CDControlDropDown.Value = 'AutoSlice';
            app.QCSBinWidthEditFieldLabel = uilabel(app.ConfigurationTab);
            app.QCSBinWidthEditFieldLabel.Text = 'QCS Bin Width';
            app.QCSBinWidthEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.CDBinWidthEditFieldLabel = uilabel(app.ConfigurationTab);
            app.CDBinWidthEditFieldLabel.Text = 'CD Bin Width';
            app.CDBinWidthEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.ActuatorWidthEditFieldLabel = uilabel(app.ConfigurationTab);
            app.ActuatorWidthEditFieldLabel.Text = 'Actuator Width';
            app.ActuatorWidthEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.LoadButton = uibutton(app.ConfigurationTab, 'push');
            app.LoadButton.ButtonPushedFcn = createCallbackFcn(app, @LoadButtonPushed, true);
            app.LoadButton.Text = 'Load';
            app.SaveButton = uibutton(app.ConfigurationTab, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.LoActuatorOffsetEditFieldLabel = uilabel(app.ConfigurationTab);
            app.LoActuatorOffsetEditFieldLabel.Text = 'Lo Actuator Offset';
            app.LoActuatorOffsetEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.HiActuatorOffsetEditFieldLabel = uilabel(app.ConfigurationTab);
            app.HiActuatorOffsetEditFieldLabel.Text = 'Hi Actuator Offset';
            app.HiActuatorOffsetEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.LoScannerEdgeEditFieldLabel = uilabel(app.ConfigurationTab);
            app.LoScannerEdgeEditFieldLabel.Text = 'Lo Scanner Edge';
            app.LoScannerEdge_cdbinEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.HiScannerEdgeEditFieldLabel = uilabel(app.ConfigurationTab);
            app.HiScannerEdgeEditFieldLabel.Text = 'Hi Scanner Edge';
            app.HiScannerEdge_cdbinEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.ScannerIDEditFieldLabel = uilabel(app.ConfigurationTab);
            app.ScannerIDEditFieldLabel.Text = 'Scanner ID';
            app.ScannerIDEditField = uieditfield(app.ConfigurationTab, 'text');
            app.UnitsToggleButton = uibutton(app.ConfigurationTab, 'state');
            app.UnitsToggleButton.ValueChangedFcn = createCallbackFcn(app, @UnitsToggleButtonValueChanged, true);
            app.UnitsToggleButton.Text = 'Width Unit';
            app.NoActuatorsLabel = uilabel(app.ConfigurationTab);
            app.NoActuatorsLabel.Text = 'No. of Actuators';
            app.NumOfActuatorsEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.NoofQCSBinsEditFieldLabel = uilabel(app.ConfigurationTab);
            app.NoofQCSBinsEditFieldLabel.Text = 'No. of QCS Bins';
            app.NumOfQCSbinsEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.NoofCDBinsEditFieldLabel = uilabel(app.ConfigurationTab);
            app.NoofCDBinsEditFieldLabel.Text = 'No. of CD Bins';
            app.NumOfCDbinsEditField = uieditfield(app.ConfigurationTab, 'numeric');
            app.CurrentMappingLabel = uilabel(app.ConfigurationTab);
            app.CurrentMappingLabel.Text = 'Current Mapping';
            app.LoadTab = uitab(app.TabGroup);
            app.LoadTab.Title = 'Load';
            app.PreprocessTab = uitab(app.TabGroup);
            app.PreprocessTab.Title = 'Preprocess';
            app.SpatialTab = uitab(app.TabGroup);
            app.SpatialTab.Title = 'Spatial';
            app.ProfileAxes = uiaxes(app.SpatialTab);
            title(app.ProfileAxes, 'Title')
            xlabel(app.ProfileAxes, 'X')
            ylabel(app.ProfileAxes, 'Y')
            zlabel(app.ProfileAxes, 'Z')
            app.SpatialTitleLabel = uilabel(app.SpatialTab);
            app.SpatialTitleLabel.Text = 'Spatial Analysis';
            app.RunSpatialButton = uibutton(app.SpatialTab, 'push');
            app.RunSpatialButton.ButtonPushedFcn = createCallbackFcn(app, @RunSpatialButtonPushed, true);
            app.RunSpatialButton.Text = 'Run Spatial';
            app.CoMResultLabel = uilabel(app.SpatialTab);
            app.CoMResultLabel.Tooltip = {'Center of Mass mm'};
            app.CoMResultLabel.Text = 'Center of Mass: -- mm';
            app.WidthResultLabel = uilabel(app.SpatialTab);
            app.WidthResultLabel.Tooltip = {'Measured Width mm'};
            app.WidthResultLabel.Text = 'Measured Width: -- mm';
            app.ExpectedWidthLabel = uilabel(app.SpatialTab);
            app.ExpectedWidthLabel.Tooltip = {'Expected Width mm'};
            app.ExpectedWidthLabel.Text = 'Expected Width: -- mm';
            app.DeviationLabel = uilabel(app.SpatialTab);
            app.DeviationLabel.Tooltip = {'% Deviation'};
            app.DeviationLabel.Text = 'Deviation: -- %';
            app.SymmetryLabel = uilabel(app.SpatialTab);
            app.SymmetryLabel.Tooltip = {'% Symmetry'};
            app.SymmetryLabel.Text = 'Symmetry: -- %';
            app.TemporalTab = uitab(app.TabGroup);
            app.TemporalTab.Title = 'Temporal';
            app.ReportTab = uitab(app.TabGroup);
            app.ReportTab.Title = 'Report';
            app.SyntheticDataGeneratorTab = uitab(app.TabGroup);
            app.SyntheticDataGeneratorTab.Title = 'Synthetic Data Generator';
            app.UIAxes = uiaxes(app.SyntheticDataGeneratorTab);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.GenerateButton = uibutton(app.SyntheticDataGeneratorTab, 'push');
            app.GenerateButton.ButtonPushedFcn = createCallbackFcn(app, @GenerateButtonPushed, true);
            app.GenerateButton.Text = 'Generate & Open';
            app.SaveConfigButton = uibutton(app.SyntheticDataGeneratorTab, 'push');
            app.SaveConfigButton.ButtonPushedFcn = createCallbackFcn(app, @SaveConfigButtonPushed, true);
            app.SaveConfigButton.Text = 'Save Config';
            app.TabGroup2 = uitabgroup(app.SyntheticDataGeneratorTab);
            app.QCSScanDynamicsTab = uitab(app.TabGroup2);
            app.QCSScanDynamicsTab.Title = 'QCS Scan & Dynamics';
            app.SampleRateEditFieldLabel = uilabel(app.QCSScanDynamicsTab);
            app.SampleRateEditFieldLabel.Text = 'Sample Rate';
            app.SampleRateEditField = uieditfield(app.QCSScanDynamicsTab, 'numeric');
            app.ScanTimeEditFieldLabel = uilabel(app.QCSScanDynamicsTab);
            app.ScanTimeEditFieldLabel.Text = 'Scan Time';
            app.ScanTimeEditField = uieditfield(app.QCSScanDynamicsTab, 'numeric');
            app.ResponseGainEditFieldLabel = uilabel(app.QCSScanDynamicsTab);
            app.ResponseGainEditFieldLabel.Text = 'Response Gain';
            app.ResponseGainEditField = uieditfield(app.QCSScanDynamicsTab, 'numeric');
            app.TimeDelayEditFieldLabel = uilabel(app.QCSScanDynamicsTab);
            app.TimeDelayEditFieldLabel.Text = 'Time Delay';
            app.TimeDelayEditField = uieditfield(app.QCSScanDynamicsTab, 'numeric');
            app.TimeConstantEditFieldLabel = uilabel(app.QCSScanDynamicsTab);
            app.TimeConstantEditFieldLabel.Text = 'Time Constant';
            app.TimeConstantEditField = uieditfield(app.QCSScanDynamicsTab, 'numeric');
            app.NoisePanel12Tab = uitab(app.TabGroup2);
            app.NoisePanel12Tab.Title = 'Noise Panel 1 & 2';
            app.EnableNoise1CheckBox = uicheckbox(app.NoisePanel12Tab);
            app.EnableNoise1CheckBox.Text = 'Enable Noise 1';
            app.Noise1DropDownLabel = uilabel(app.NoisePanel12Tab);
            app.Noise1DropDownLabel.Text = 'Noise 1';
            app.Noise1DropDown = uidropdown(app.NoisePanel12Tab);
            app.Noise1DropDown.Items = {'Gaussian', '1/f (Pink)', 'Quantization', 'Baseline Drift'};
            app.Noise1DropDown.Value = 'Gaussian';
            app.EnableNoise2CheckBox = uicheckbox(app.NoisePanel12Tab);
            app.EnableNoise2CheckBox.Text = 'Enable Noise 2';
            app.Noise2DropDownLabel = uilabel(app.NoisePanel12Tab);
            app.Noise2DropDownLabel.Text = 'Noise 2';
            app.Noise2DropDown = uidropdown(app.NoisePanel12Tab);
            app.Noise2DropDown.Items = {'Gaussian', '1/f (Pink)', 'Quantization', 'Baseline Drift'};
            app.Noise2DropDown.Value = 'Gaussian';
            app.Level1EditFieldLabel = uilabel(app.NoisePanel12Tab);
            app.Level1EditFieldLabel.Text = 'Level 1';
            app.Level1EditField = uieditfield(app.NoisePanel12Tab, 'numeric');
            app.Level2EditFieldLabel = uilabel(app.NoisePanel12Tab);
            app.Level2EditFieldLabel.Text = 'Level 2';
            app.Level2EditField = uieditfield(app.NoisePanel12Tab, 'numeric');
            app.NoisePanel24Tab = uitab(app.TabGroup2);
            app.NoisePanel24Tab.Title = 'Noise Panel 2 & 4';
            app.EnableNoise3CheckBox = uicheckbox(app.NoisePanel24Tab);
            app.EnableNoise3CheckBox.Text = 'Enable Noise 3';
            app.EnableNoise4CheckBox = uicheckbox(app.NoisePanel24Tab);
            app.EnableNoise4CheckBox.Text = 'Enable Noise 4';
            app.Noise3DropDownLabel = uilabel(app.NoisePanel24Tab);
            app.Noise3DropDownLabel.Text = 'Noise 3';
            app.Noise3DropDown = uidropdown(app.NoisePanel24Tab);
            app.Noise3DropDown.Items = {'Gaussian', '1/f (Pink)', 'Quantization', 'Baseline Drift'};
            app.Noise3DropDown.Value = 'Gaussian';
            app.Noise4DropDownLabel = uilabel(app.NoisePanel24Tab);
            app.Noise4DropDownLabel.Text = 'Noise 4';
            app.Noise4DropDown = uidropdown(app.NoisePanel24Tab);
            app.Noise4DropDown.Items = {'Gaussian', '1/f (Pink)', 'Quantization', 'Baseline Drift'};
            app.Noise4DropDown.Value = 'Gaussian';
            app.Level3EditFieldLabel = uilabel(app.NoisePanel24Tab);
            app.Level3EditFieldLabel.Text = 'Level 3';
            app.Level3EditField = uieditfield(app.NoisePanel24Tab, 'numeric');
            app.Level4EditFieldLabel = uilabel(app.NoisePanel24Tab);
            app.Level4EditFieldLabel.Text = 'Level 4';
            app.Level4EditField = uieditfield(app.NoisePanel24Tab, 'numeric');
            app.LoadConfigButton = uibutton(app.SyntheticDataGeneratorTab, 'push');
            app.LoadConfigButton.Text = 'Load Config';
            app.StatusLabel = uilabel(app.UIFigure);
            app.StatusLabel.Text = 'Ready';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = QCS_ActuatorApp

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end